classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        EquestionEditFieldLabel      matlab.ui.control.Label
        EquestionEditField           matlab.ui.control.EditField
        Label                        matlab.ui.control.Label
        DropDown                     matlab.ui.control.DropDown
        maxIterationsEditFieldLabel  matlab.ui.control.Label
        maxIterationsEditField       matlab.ui.control.NumericEditField
        EpsEditFieldLabel            matlab.ui.control.Label
        EpsEditField                 matlab.ui.control.NumericEditField
        X0EditFieldLabel             matlab.ui.control.Label
        X0EditField                  matlab.ui.control.NumericEditField
        X1EditFieldLabel             matlab.ui.control.Label
        X1EditField                  matlab.ui.control.NumericEditField
        SolveButton                  matlab.ui.control.Button
        iterationsEditFieldLabel     matlab.ui.control.Label
        iterationsEditField          matlab.ui.control.NumericEditField
        timeEditFieldLabel           matlab.ui.control.Label
        timeEditField                matlab.ui.control.NumericEditField
        approx_rootEditFieldLabel    matlab.ui.control.Label
        approx_rootEditField         matlab.ui.control.NumericEditField
        UITable                      matlab.ui.control.Table
        UITable2                     matlab.ui.control.Table
        RootsLabel                   matlab.ui.control.Label
        percisionsLabel              matlab.ui.control.Label
        UITable3                     matlab.ui.control.Table
        XLLabel                      matlab.ui.control.Label
        UITable3_2                   matlab.ui.control.Table
        XULabel                      matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
           format short;
            eqn = app.EquestionEditField.Value;
            fun = char(eqn);
            x0=app.X0EditField.Value;
            x1=app.X1EditField.Value;
            maxittr=app.maxIterationsEditField.Value;
            eps=app.EpsEditField.Value;
            method=app.DropDown.Value;
            if strcmpi(method,'Bisection')
             [opmaxittr,oproots,oppercs,optime,opxu,opxl] = bisectionMethod(fun,maxittr,eps,x0,x1);
         
            elseif strcmpi(method,'False-position')
             [opmaxittr,oproots,oppercs,optime,opxu,opxl] = falsePositionMethod(fun,maxittr,eps,x0,x1);
         
            elseif strcmpi(method,'Newton-Raphson')
             [opmaxittr,oproots,oppercs,optime] = newtonRaphasonMethod(fun,maxittr,eps,x0);
            
            elseif strcmpi(method,'Secant')
             [opmaxittr,oproots,oppercs,optime] = secantMethod(fun,maxittr,eps,x0,x1); 
             elseif strcmpi(method,'Fixed point')
             [opmaxittr,oproots,oppercs,optime] = fixedPoinMethod(fun,maxittr,eps,x0);
            elseif strcmpi(method,'general algorithim')
             [opmaxittr,oproots,oppercs,optime] = Bierge_vieta(fun,maxittr,eps,x0); 
            
            end
            app.iterationsEditField.Value=opmaxittr;
            app.approx_rootEditField.Value=oproots(opmaxittr-1);
            app.UITable.Visible=true; 
            app.UITable2.Visible=true;
            app.UITable.Data=oproots;
            app.UITable2.Data=oppercs;
           app.timeEditField.Value=optime;
           if(  strcmpi(method,'Bisection') ||  strcmpi(method,'False-position') )
               app.UITable3.Visible=true;
               app.UITable3_2.Visible=true;
               app.UITable3.Data=opxl;
               app.UITable3_2.Data=opxu;
           else
                app.UITable3.Visible=false;
               app.UITable3_2.Visible=false;
           end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.EquestionEditFieldLabel = uilabel(app.UIFigure);
            app.EquestionEditFieldLabel.Text = 'Equestion';
            app.EquestionEditField = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Bisection', 'False-position', 'Fixed point', 'Newton-Raphson', 'Secant', 'general algorithim'};
            app.DropDown.Value = 'Bisection';
            app.maxIterationsEditFieldLabel = uilabel(app.UIFigure);
            app.maxIterationsEditFieldLabel.Text = 'maxIterations';
            app.maxIterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.maxIterationsEditField.Value = 50;
            app.EpsEditFieldLabel = uilabel(app.UIFigure);
            app.EpsEditFieldLabel.Text = 'Eps';
            app.EpsEditField = uieditfield(app.UIFigure, 'numeric');
            app.EpsEditField.Value = 1e-05;
            app.X0EditFieldLabel = uilabel(app.UIFigure);
            app.X0EditFieldLabel.Text = 'X0';
            app.X0EditField = uieditfield(app.UIFigure, 'numeric');
            app.X1EditFieldLabel = uilabel(app.UIFigure);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.UIFigure, 'numeric');
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.iterationsEditFieldLabel = uilabel(app.UIFigure);
            app.iterationsEditFieldLabel.Enable = 'off';
            app.iterationsEditFieldLabel.Text = 'iterations';
            app.iterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.iterationsEditField.Editable = 'off';
            app.iterationsEditField.Enable = 'off';
            app.timeEditFieldLabel = uilabel(app.UIFigure);
            app.timeEditFieldLabel.Enable = 'off';
            app.timeEditFieldLabel.Text = 'time';
            app.timeEditField = uieditfield(app.UIFigure, 'numeric');
            app.timeEditField.Editable = 'off';
            app.timeEditField.Enable = 'off';
            app.approx_rootEditFieldLabel = uilabel(app.UIFigure);
            app.approx_rootEditFieldLabel.Enable = 'off';
            app.approx_rootEditFieldLabel.Text = 'approx_root';
            app.approx_rootEditField = uieditfield(app.UIFigure, 'numeric');
            app.approx_rootEditField.Editable = 'off';
            app.approx_rootEditField.Enable = 'off';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'};
            app.UITable.RowName = {};
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'};
            app.UITable2.RowName = {};
            app.RootsLabel = uilabel(app.UIFigure);
            app.RootsLabel.Enable = 'off';
            app.RootsLabel.Text = 'Roots';
            app.percisionsLabel = uilabel(app.UIFigure);
            app.percisionsLabel.Enable = 'off';
            app.percisionsLabel.Text = 'percisions';
            app.UITable3 = uitable(app.UIFigure);
            app.UITable3.ColumnName = {'1'; '2'; '3'; '4'; '5'; '6'};
            app.UITable3.RowName = {};
            app.UITable3.Visible = 'off';
            app.XLLabel = uilabel(app.UIFigure);
            app.XLLabel.Text = 'XL';
            app.UITable3_2 = uitable(app.UIFigure);
            app.UITable3_2.ColumnName = {'1'; '2'; '3'; '4'};
            app.UITable3_2.RowName = {};
            app.UITable3_2.Visible = 'off';
            app.XULabel = uilabel(app.UIFigure);
            app.XULabel.Text = 'XU';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end